classdef gameUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        ScoreEditField       matlab.ui.control.NumericEditField
        ScoreEditFieldLabel  matlab.ui.control.Label
        Rock_Obstacle        matlab.ui.control.Image
        StartGameButton      matlab.ui.control.StateButton
        Player               matlab.ui.control.Image
        OceanBackground      matlab.ui.control.Image
        UIAxes               matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        % Keeps track of the current game state 
        % 0 = Game not running; 1 = Game running
        gameRunning; 
    end
    
    methods (Access = public)
        
        function obstacleMovement(app, event)
            obstacleVelocity = 1;
            obstacleCurrPos = app.Rock_Obstacle.Position;
            constYPos = 100;
            obstacleCurrPos(2) = constYPos;
            offScreenXPos = 0;

            while obstacleCurrPos(1) > offScreenXPos
                obstacleCurrPos(1) = obstacleCurrPos(1) - obstacleVelocity; 
                app.Rock_Obstacle.Position = obstacleCurrPos;
                drawnow;
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: StartGameButton
        function StartButtonPressed(app, event)
            % value is 0 on default/unpressed, 1 when pressed
            app.gameRunning = app.StartGameButton.Value; 

            if ~app.gameRunning   
                % If the game is not running
                % Display "Start Game" button
                app.StartGameButton.Text = "Start Game";
                % Hide Images
                app.Player.Visible = "off";
                app.Rock_Obstacle.Visible = "off";
            else                   
                % If the game is running
                % Display "End Game" button
                app.StartGameButton.Text = "End Game";
                % Show Images
                app.Player.Visible = "on";
                app.Rock_Obstacle.Visible = "on";

                % Make the obstacle move
                obstacleMovement(app, event);
            end   
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            initialY = 100; % Initial Y position
            jumpHeight = 100; % Height to jump
            velocity = 5; % Velocity of jump

            if strcmp(key, 'space') % Check if spacebar is pressed
                % Get the current position of the image
                currentPosition = app.Player.Position;
                currentY = currentPosition(2);
                
                % Jump animation
                while currentY < initialY + jumpHeight
                    % Move the image up
                    currentY = currentY + velocity;
                    app.Player.Position = [currentPosition(1) currentY currentPosition(3:4)];
                    drawnow; % Update the figure
                end

                % Come back down
                while currentY > initialY
                    % Move the image down
                    currentY = currentY - velocity
                    app.Player.Position = [currentPosition(1) currentY currentPosition(3:4)];
                    drawnow; % Update the figure
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.OceanBackground = uiimage(app.UIFigure);
            app.OceanBackground.Tag = 'oceangif';
            app.OceanBackground.AltText = 'Ocean';
            app.OceanBackground.ImageSource = fullfile(pathToMLAPP, 'ocean_bg.gif');
            app.Player = uiimage(app.UIFigure);
            app.Player.Visible = 'off';
            app.Player.ImageSource = fullfile(pathToMLAPP, 'mickey_mouse.png');
            app.StartGameButton = uibutton(app.UIFigure, 'state');
            app.StartGameButton.ValueChangedFcn = createCallbackFcn(app, @StartButtonPressed, true);
            app.StartGameButton.Text = 'Start Game';
            app.Rock_Obstacle = uiimage(app.UIFigure);
            app.Rock_Obstacle.Visible = 'off';
            app.Rock_Obstacle.ImageSource = fullfile(pathToMLAPP, 'rock_obstacle.jpg');
            app.ScoreEditFieldLabel = uilabel(app.UIFigure);
            app.ScoreEditFieldLabel.Text = 'Score';
            app.ScoreEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gameUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end